classdef test_datatip < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UITable   matlab.ui.control.Table
        UIAxes    matlab.ui.control.UIAxes
    end


    methods (Access = private)

        function app = setupDatatipListener(app)
            % Set up the UITable to store datatip info with an additional 'Label' column
            app.UITable.ColumnName = {'X', 'Y', 'Value', 'Label'};
            app.UITable.Data = []; % Initialize as empty
            app.UITable.ColumnEditable = [false, false, false, true];  % Only the 'Label' column is editable
            app.UITable.ColumnWidth = {50, 50, 50, 150};
            app.UITable.Position(3) = 300;

            % Get the DataTipTemplate from the axes
            dcm_obj = datacursormode(ancestor(app.UIAxes, 'figure'));
            set(dcm_obj, 'UpdateFcn', @(src, event) createDatatip(src, event, app.UITable));
            dcm_obj.Enable = 'on'; % Enable data cursor mode

            % Function to handle new datatips
            function txt = createDatatip(~, event_obj, UITable)
                pos = get(event_obj, 'Position');
                % Map the position to the closest x and y indices in the defined axes
                xIndex = findClosest(app.UIAxes.XLim, pos(1));
                yIndex = findClosest(app.UIAxes.YLim, pos(2));

                % Safely access the CData with these indices
                if xIndex >= 1 && yIndex >= 1 && xIndex <= size(event_obj.Target.CData, 2) && yIndex <= size(event_obj.Target.CData, 1)
                    val = event_obj.Target.CData(yIndex, xIndex);
                else
                    val = NaN; % In case indices are out of range
                end

                % Update the table data
                newX = app.UIAxes.XLim(xIndex);
                newY = app.UIAxes.YLim(yIndex);
                newEntry = {newX, newY, val, ''}; % Initialize label as empty string
                currentData = get(UITable, 'Data');
                newTableData = [currentData; newEntry];
                set(UITable, 'Data', newTableData);

                % Provide text for the datatip
                txt = {['X: ', num2str(newX)], ...
                    ['Y: ', num2str(newY)], ...
                    ['Value: ', num2str(val)], ...
                    'Label: '}; % Add label placeholder in datatip text (optional)
            end

            function index = findClosest(values, target)
                [~, index] = min(abs(values - target));
            end
        end

        function deleteRow(app)
            % Get the index of the selected row
            idx = app.UITable.Selection;
            assignin('base','idx',idx);

            % Check if any row is selected
            if isempty(idx)
                uialert(app.UIFigure, 'No row selected', 'Row Deletion Error');
                return;
            end
            app.UITable.Data(idx(1), :) = [];  % Remove the row from the data
        end

        function moveabs(app)
            test = 234;
            assignin('base','test',test);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            vec1 = 50:0.1:51;
            imagesc(app.UIAxes,vec1,vec1,rand(length(vec1),length(vec1)));
            app = setupDatatipListener(app);

            % Create a context menu for the table
            cmenu = uicontextmenu(app.UIFigure);  % app.UIFigure is typically the parent figure of the app
            uimenu(cmenu, 'Text', 'Delete Row', 'MenuSelectedFcn', @(src, event) deleteRow(app));
            uimenu(cmenu, 'Text', 'Go', 'MenuSelectedFcn', @(src, event) moveabs(app));

            % Assign the context menu to the table
            app.UITable.ContextMenu = cmenu;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X'; 'Y'; 'Value'; 'Label'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test_datatip

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end